classdef Assignment < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Panel                         matlab.ui.container.Panel
        PowerConverterCircuitSimulatorLabel  matlab.ui.control.Label
        RippleFactorEditField         matlab.ui.control.NumericEditField
        RippleFactorEditFieldLabel    matlab.ui.control.Label
        VoltagerippleVEditField       matlab.ui.control.NumericEditField
        VoltagerippleVEditFieldLabel  matlab.ui.control.Label
        ResistanceohmsSlider          matlab.ui.control.Slider
        ResistanceohmsSliderLabel     matlab.ui.control.Label
        CapacitanceuFSlider           matlab.ui.control.Slider
        CapacitanceuFLabel            matlab.ui.control.Label
        TimesSlider                   matlab.ui.control.Slider
        TimesLabel                    matlab.ui.control.Label
        CircuitDropDown               matlab.ui.control.DropDown
        CircuitLabel                  matlab.ui.control.Label
        WaveformDropDown              matlab.ui.control.DropDown
        WaveformLabel                 matlab.ui.control.Label
        seperatePlots                 matlab.ui.control.CheckBox
        smoothing                     matlab.ui.control.CheckBox
        Voltage                       matlab.ui.control.Slider
        VoltageLabel_2                matlab.ui.control.Label
        Frequency                     matlab.ui.control.Slider
        VoltageLabel                  matlab.ui.control.Label
        SimulateButton                matlab.ui.control.Button
        inputAxes                     matlab.ui.control.UIAxes
        outputAxes                    matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        
        function plotHalfWave(app, simGoBrrr)

            if app.seperatePlots.Value == 1
                % Plot on seperate plots
                cla(app.outputAxes);
                cla(app.inputAxes);
                plot(app.outputAxes, simGoBrrr.outputhw.Time, simGoBrrr.outputhw.Data, 'Color','#66fa8a', LineWidth=1.5);
                legend(app.inputAxes, "Output");
                plot(app.inputAxes, simGoBrrr.inputwf.Time, simGoBrrr.inputwf.Data, 'Color','#fa6666', LineWidth=1.5); 
                legend(app.inputAxes, "\color{white} Input");
                legend(app.outputAxes, "\color{white} Output");

                % Find and show ripple values
                findRipple(app, simGoBrrr.outputhw.Time, simGoBrrr.outputhw.Data);
                
            else
                % Plot on same plot
                cla(app.outputAxes);
                cla(app.inputAxes);
                hold(app.inputAxes,"on");
                plot(app.inputAxes, simGoBrrr.inputwf.Time, simGoBrrr.inputwf.Data, 'Color','#fa6666', LineWidth=1.5);  
                plot(app.inputAxes, simGoBrrr.outputhw.Time, simGoBrrr.outputhw.Data, 'Color','#66fa8a', LineWidth=1.5);
                legend(app.inputAxes, "\color{white} Input", "\color{white} Output");
                hold(app.inputAxes,"off");
                
                % Find and show ripple values
                findRipple(app, simGoBrrr.outputhw.Time, simGoBrrr.outputhw.Data);

            end
        end
        
        function plotFullWave(app, simGoBrrr)
            if app.seperatePlots.Value == 1
                % plot on different plots
                cla(app.outputAxes);
                cla(app.inputAxes);
                plot(app.outputAxes, simGoBrrr.outputfw.Time, simGoBrrr.outputfw.Data,  'Color','#66fa8a', LineWidth=1.5);
                legend(app.inputAxes, "Input");
                plot(app.inputAxes, simGoBrrr.inputwf.Time, simGoBrrr.inputwf.Data, 'Color','#fa6666', LineWidth=1.5); 
                legend(app.inputAxes, "\color{white} Input");
                legend(app.outputAxes, "\color{white} Output");

                % Find and show ripple values
                findRipple(app, simGoBrrr.outputfw.Time, simGoBrrr.outputfw.Data);
            else
                % Plot on same plot
                cla(app.outputAxes);
                cla(app.inputAxes);
                hold(app.inputAxes,"on");
                plot(app.inputAxes, simGoBrrr.inputwf.Time, simGoBrrr.inputwf.Data, 'Color','#fa6666', LineWidth=1.5);  
                plot(app.inputAxes, simGoBrrr.outputfw.Time, simGoBrrr.outputfw.Data, 'Color','#66fa8a', LineWidth=1.5);
                legend(app.inputAxes, "\color{white} Input", "\color{white} Output");
                hold(app.inputAxes,"off");

                findRipple(app, simGoBrrr.outputfw.Time, simGoBrrr.outputfw.Data);
            end
            
        end

        function findRipple(app, simTime, simVoltage)
            % Finding ripple using min and max voltage            
            % Find the maximum point
            [Vmax, indexMax] = max(simVoltage);
            tMax = simTime(indexMax);
            
            % Define the time range within 1/f seconds before the maximum point
            tRange = [tMax - (1/app.Frequency.Value), tMax];
            
            % Find the indices of time points within the defined range
            indicesInRange = simTime >= tRange(1) & simTime <= tRange(2);
            
            % Extract the voltage values within the specified time range
            voltageInRange = simVoltage(indicesInRange);
            
            % Find the minimum y-value within the time range
            Vmin = min(voltageInRange);
            
            ripple = Vmax - Vmin;
            app.VoltagerippleVEditField.Value = ripple;
            % Ripple factor = ripple / Vmax
            rippleFactor = ripple / app.Voltage.Value;
            app.RippleFactorEditField.Value = rippleFactor;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.inputAxes.Title.Color = '#F5F2FF';
            app.outputAxes.Title.Color = '#F5F2FF';
            
        end

        % Value changed function: seperatePlots
        function seperatePlotsValueChanged(app, event)
            value = app.seperatePlots.Value;
             if value == 0
                 app.outputAxes.Visible = "off";
                 
             else 
                 app.outputAxes.Visible = "on";
 
             end
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)

            %Use variables for simulation
            assignin('base', 'frequency', app.Frequency.Value);
            assignin('base', 'peakVoltage', app.Voltage.Value);
            assignin('base', 'resistance', (app.ResistanceohmsSlider.Value));
            if app.smoothing.Value == 1
                assignin('base', 'capacitance', (app.CapacitanceuFSlider.Value)*10^(-6));
            else
                assignin('base', 'capacitance', 0.00);
            end

            %Simulate and plot
            simGoBrrr = sim('Halfwave.slx', 'StopTime', num2str(app.TimesSlider.Value));
            if app.CircuitDropDown.Value == "Half-Wave Rectifier"
                plotHalfWave(app, simGoBrrr);

            elseif app.CircuitDropDown.Value == "Full-Wave Rectifier"
                plotFullWave(app, simGoBrrr);
            end
           

        end

        % Value changed function: WaveformDropDown
        function WaveformDropDownValueChanged(app, event)
            value = app.WaveformDropDown.Value;
            
            switch value
                case 'Sine'
                    selectedIndex = 1;
                case 'Square'
                    selectedIndex = 2;
                case 'Sawtooth'
                    selectedIndex = 3;
                case 'Weird'
                    selectedIndex = 4;
                otherwise
                    selectedIndex = 1;
            end
            selectorBlockPath = ['Halfwave', '/waveSelect'];
            set_param(selectorBlockPath, 'Elements', num2str(selectedIndex));
            set_param('Halfwave', 'SimulationCommand', 'Update');


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.outputAxes = uiaxes(app.Panel);
            xlabel(app.outputAxes, 'Time')
            ylabel(app.outputAxes, 'Voltage')
            zlabel(app.outputAxes, 'Z')
            app.inputAxes = uiaxes(app.Panel);
            xlabel(app.inputAxes, 'Time')
            ylabel(app.inputAxes, 'Voltage')
            zlabel(app.inputAxes, 'Z')
            app.SimulateButton = uibutton(app.Panel, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.VoltageLabel = uilabel(app.Panel);
            app.VoltageLabel.Text = 'Frequency (Hz):';
            app.Frequency = uislider(app.Panel);
            app.Frequency.Limits = [1 120];
            app.Frequency.MajorTicks = [1 20 40 60 80 100 120];
            app.Frequency.MinorTicks = [1 4 8 12 16 20 24 28 32 36 40 44 48 52 56 60 64 68 72 76 80 84 88 92 96 100 104 108 112 116 120];
            app.Frequency.Value = 60;
            app.VoltageLabel_2 = uilabel(app.Panel);
            app.VoltageLabel_2.Text = 'Voltage (V):';
            app.Voltage = uislider(app.Panel);
            app.Voltage.Limits = [0 240];
            app.Voltage.Value = 120;
            app.smoothing = uicheckbox(app.Panel);
            app.smoothing.Text = 'Smoothing (Capacitor)';
            app.smoothing.Value = true;
            app.seperatePlots = uicheckbox(app.Panel);
            app.seperatePlots.ValueChangedFcn = createCallbackFcn(app, @seperatePlotsValueChanged, true);
            app.seperatePlots.Text = 'Seperate plots';
            app.WaveformLabel = uilabel(app.Panel);
            app.WaveformLabel.Text = 'Waveform:';
            app.WaveformDropDown = uidropdown(app.Panel);
            app.WaveformDropDown.Items = {'Sine', 'Square', 'Sawtooth', 'Weird'};
            app.WaveformDropDown.ValueChangedFcn = createCallbackFcn(app, @WaveformDropDownValueChanged, true);
            app.WaveformDropDown.Value = 'Sine';
            app.CircuitLabel = uilabel(app.Panel);
            app.CircuitLabel.Text = 'Circuit:';
            app.CircuitDropDown = uidropdown(app.Panel);
            app.CircuitDropDown.Items = {'Half-Wave Rectifier', 'Full-Wave Rectifier'};
            app.CircuitDropDown.Value = 'Half-Wave Rectifier';
            app.TimesLabel = uilabel(app.Panel);
            app.TimesLabel.Text = 'Time (s):';
            app.TimesSlider = uislider(app.Panel);
            app.TimesSlider.Limits = [0 1];
            app.TimesSlider.Value = 0.1;
            app.CapacitanceuFLabel = uilabel(app.Panel);
            app.CapacitanceuFLabel.Text = 'Capacitance (uF):';
            app.CapacitanceuFSlider = uislider(app.Panel);
            app.CapacitanceuFSlider.Limits = [0 250];
            app.CapacitanceuFSlider.Value = 100;
            app.ResistanceohmsSliderLabel = uilabel(app.Panel);
            app.ResistanceohmsSliderLabel.Text = 'Resistance (ohms)';
            app.ResistanceohmsSlider = uislider(app.Panel);
            app.ResistanceohmsSlider.Limits = [1 250];
            app.ResistanceohmsSlider.MajorTicks = [1 50 100 150 200 250];
            app.ResistanceohmsSlider.MajorTickLabels = {'1', '50', '100', '150', '200', '250'};
            app.ResistanceohmsSlider.MinorTicks = [1 10 20 30 40 50 60 70 80 90 100 110 120 130 140 150 160 170 180 190 200 210 220 230 240 250];
            app.ResistanceohmsSlider.Value = 50;
            app.VoltagerippleVEditFieldLabel = uilabel(app.Panel);
            app.VoltagerippleVEditFieldLabel.Text = 'Voltage ripple (V)';
            app.VoltagerippleVEditField = uieditfield(app.Panel, 'numeric');
            app.VoltagerippleVEditField.Editable = 'off';
            app.RippleFactorEditFieldLabel = uilabel(app.Panel);
            app.RippleFactorEditFieldLabel.Text = 'Ripple Factor';
            app.RippleFactorEditField = uieditfield(app.Panel, 'numeric');
            app.RippleFactorEditField.Editable = 'off';
            app.PowerConverterCircuitSimulatorLabel = uilabel(app.Panel);
            app.PowerConverterCircuitSimulatorLabel.Text = 'Power Converter Circuit Simulator';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Assignment

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end